classdef Heart_Barras < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        EligeunaopcinLabel   matlab.ui.control.Label
        EnfermedadesCardiovascularesporGneroyEdadLabel  matlab.ui.control.Label
        aosenadelanteButton  matlab.ui.control.Button
        a60aosButton         matlab.ui.control.Button
        a45aosButton         matlab.ui.control.Button
        a30aosButton         matlab.ui.control.Button
        a18aosButton         matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
  

        end

        % Button pushed function: a18aosButton
        function a18aosButtonPushed(app, event)
% Definir los datos actualizados
x = 0; % Número actualizado de mujeres de 0 a 18 años con la enfermedad
y = 0; % Número actualizado de hombres de 0 a 18 años con la enfermedad

% Preparar las etiquetas actualizadas
etiquetas = {'Mujeres', 'Hombres'};

% Preparar los datos para la gráfica de barras
datos_x = 1:numel(etiquetas);
datos_y = [x, y];

% Mostrar los datos en la gráfica de barras
bar(app.UIAxes, datos_x, datos_y);

% Establecer las etiquetas del eje X
app.UIAxes.XTick = datos_x;
app.UIAxes.XTickLabel = etiquetas;

% Activar la cuadrícula en ambos ejes
app.UIAxes.XGrid = 'on';
app.UIAxes.YGrid = 'on';

% Agregar título al gráfico
title(app.UIAxes, 'Personas con la enfermedad de 0 - 18');


        end

        % Button pushed function: aosenadelanteButton
        function aosenadelanteButtonPushed(app, event)
        % Definir los datos actualizados
x = [1326]; % Número de mujeres de 60 años en adelante con la enfermedad
y = [1866]; % Número de hombres de 60 años en adelante con la enfermedad

% Preparar las etiquetas actualizadas
etiquetas = {'Mujeres (60+)', 'Hombres (60+)'};

% Preparar los datos para la gráfica de barras
datos_x = 1:numel(etiquetas);
datos_y = [x, y];

% Mostrar los datos en la gráfica de barras
bar(app.UIAxes, datos_x, datos_y);

% Establecer las etiquetas del eje X
xticks(app.UIAxes, datos_x);
xticklabels(app.UIAxes, etiquetas);

% Activar la cuadrícula en ambos ejes
app.UIAxes.XGrid = 'on';
app.UIAxes.YGrid = 'on';

% Agregar título al gráfico
title(app.UIAxes, 'Personas con la enfermedad por grupo de 60 en adelante');

        end

        % Button pushed function: a30aosButton
        function a30aosButtonPushed(app, event)
% Definir los datos actualizados
x = [1]; % Número de mujeres de 18 a 30 años con la enfermedad
y = [3]; % Número de hombres de 18 a 30 años con la enfermedad

% Preparar las etiquetas actualizadas
etiquetas = {'Mujeres (18-30)', 'Hombres (18-30)'};

% Preparar los datos para la gráfica de barras
datos_x = 1:numel(etiquetas);
datos_y = [x, y];
% Definir los colores para las barras
colores = {[1 0 1], [0 0 1]}; % Rosa para mujeres, azul para hombres

% Mostrar los datos en la gráfica de barras
bar(app.UIAxes, datos_x, datos_y);

% Establecer las etiquetas del eje X
xticks(app.UIAxes, datos_x);
xticklabels(app.UIAxes, etiquetas);

% Activar la cuadrícula en ambos ejes
app.UIAxes.XGrid = 'on';
app.UIAxes.YGrid = 'on';

% Agregar título al gráfico
title(app.UIAxes, 'Personas con la enfermedad por grupo de 18 - 30');


        end

        % Button pushed function: a45aosButton
        function a45aosButtonPushed(app, event)
            % Definir los datos actualizados
x = [4778]; % Número de mujeres de 30 a 45 años con la enfermedad
y = [2780]; % Número de hombres de 30 a 45 años con la enfermedad

% Preparar las etiquetas actualizadas
etiquetas = {'Mujeres (30-45)', 'Hombres (30-45)'};

% Preparar los datos para la gráfica de barras
datos_x = 1:numel(etiquetas);
datos_y = [x, y];

% Mostrar los datos en la gráfica de barras
bar(app.UIAxes, datos_x, datos_y);

% Establecer las etiquetas del eje X
xticks(app.UIAxes, datos_x);
xticklabels(app.UIAxes, etiquetas);

% Activar la cuadrícula en ambos ejes
app.UIAxes.XGrid = 'on';
app.UIAxes.YGrid = 'on';

% Agregar título al gráfico
title(app.UIAxes, 'Personas con la enfermedad por grupo de 30 - 45');


        end

        % Button pushed function: a60aosButton
        function a60aosButtonPushed(app, event)
            % Definir los datos actualizados
x = [8010]; % Número de mujeres de 45 a 60 años con la enfermedad
y = [16267]; % Número de hombres de 45 a 60 años con la enfermedad

% Preparar las etiquetas actualizadas
etiquetas = {'Mujeres (45-60)', 'Hombres (45-60)'};

% Preparar los datos para la gráfica de barras
datos_x = 1:numel(etiquetas);
datos_y = [x, y];

% Mostrar los datos en la gráfica de barras
bar(app.UIAxes, datos_x, datos_y);

% Establecer las etiquetas del eje X
xticks(app.UIAxes, datos_x);
xticklabels(app.UIAxes, etiquetas);

% Activar la cuadrícula en ambos ejes
app.UIAxes.XGrid = 'on';
app.UIAxes.YGrid = 'on';

% Agregar título al gráfico
title(app.UIAxes, 'Personas con la enfermedad por grupo de 45 - 60');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Edad')
            ylabel(app.UIAxes, 'E. Cardiovascular')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.a18aosButton = uibutton(app.UIFigure, 'push');
            app.a18aosButton.ButtonPushedFcn = createCallbackFcn(app, @a18aosButtonPushed, true);
            app.a18aosButton.Text = {'0 a 18 años'; ''};
            app.a30aosButton = uibutton(app.UIFigure, 'push');
            app.a30aosButton.ButtonPushedFcn = createCallbackFcn(app, @a30aosButtonPushed, true);
            app.a30aosButton.Text = {'18 a 30 años'; ''};
            app.a45aosButton = uibutton(app.UIFigure, 'push');
            app.a45aosButton.ButtonPushedFcn = createCallbackFcn(app, @a45aosButtonPushed, true);
            app.a45aosButton.Text = {'30 a 45 años'; ''};
            app.a60aosButton = uibutton(app.UIFigure, 'push');
            app.a60aosButton.ButtonPushedFcn = createCallbackFcn(app, @a60aosButtonPushed, true);
            app.a60aosButton.Text = {'45 a 60 años'; ''};
            app.aosenadelanteButton = uibutton(app.UIFigure, 'push');
            app.aosenadelanteButton.ButtonPushedFcn = createCallbackFcn(app, @aosenadelanteButtonPushed, true);
            app.aosenadelanteButton.Text = {'60 años en adelante'; ''};
            app.EnfermedadesCardiovascularesporGneroyEdadLabel = uilabel(app.UIFigure);
            app.EnfermedadesCardiovascularesporGneroyEdadLabel.Text = 'Enfermedades Cardiovasculares por Género y Edad';
            app.EligeunaopcinLabel = uilabel(app.UIFigure);
            app.EligeunaopcinLabel.Text = 'Elige una opción:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heart_Barras

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end