classdef Heart_Dispersion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UsuariosinEnfermedadButton_4  matlab.ui.control.Button
        UsuarioconEnfermedadButton_4  matlab.ui.control.Button
        NiveldeglucosavsPresionarterialmediaLabel  matlab.ui.control.Label
        EligeunaopcinLabel_2          matlab.ui.control.Label
        UsuariosinEnfermedadButton_3  matlab.ui.control.Button
        UsuarioconEnfermedadButton_3  matlab.ui.control.Button
        ndicedemasacorporalvsColesterolLabel  matlab.ui.control.Label
        EligeunaopcinLabel            matlab.ui.control.Label
        AnlisisdeVariablesLabel       matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function ndicedemasacorporalvsColesterolButtonPushed(app, event)

        end

        % Callback function: not associated with a component
        function ButtonGroupSelectionChanged(app, event)
      
        end

        % Button pushed function: UsuarioconEnfermedadButton_3
        function UsuarioconEnfermedadButton_3Pushed(app, event)
        % Cargar los datos de la base de datos (reemplaza 'nombre_del_archivo.csv' con el nombre de tu archivo)
        heart_data = readtable('heart_data.csv');

        % Calcular el IMC para cada individuo
        IMC = heart_data.weight ./ (heart_data.height/100).^2; % Convertir altura a metros

        % Filtrar los datos para incluir solo a las personas que padecen la enfermedad
        indices_enfermos = (heart_data.cardio == 1);
        IMC_enfermos = IMC(indices_enfermos);
        colesterol_enfermos = heart_data.cholesterol(indices_enfermos);

        % Graficar scatter plot para personas con enfermedad en app.UIAxes
        scatter(app.UIAxes, IMC_enfermos, colesterol_enfermos);
        xlabel(app.UIAxes, 'Índice de Masa Corporal (IMC)');
        ylabel(app.UIAxes, 'Colesterol');
        title(app.UIAxes, 'Scatter Plot: IMC vs Colesterol en personas con la enfermedad');
 
        end

        % Button pushed function: UsuarioconEnfermedadButton_4
        function UsuarioconEnfermedadButton_4Pushed(app, event)
        % Cargar los datos de la base de datos (reemplaza 'nombre_del_archivo.csv' con el nombre de tu archivo)
    heart_data = readtable('heart_data.csv');

    % Calcular la presión arterial media
    PresionAM = (heart_data.ap_lo * 2) + heart_data.ap_hi / 3;

    % Filtrar los datos para incluir solo a las personas que padecen la enfermedad
    indices_enfermos = (heart_data.cardio == 1);
    glucosa_enfermos = heart_data.gluc(indices_enfermos);
    PresionAM_enfermos = PresionAM(indices_enfermos);

    % Graficar scatter plot para personas con enfermedad en app.UIAxes_2
    scatter(app.UIAxes_2, glucosa_enfermos, PresionAM_enfermos);
    xlabel(app.UIAxes_2, 'Nivel de Glucosa');
    ylabel(app.UIAxes_2, 'Presión Arterial Media');
    title(app.UIAxes_2, 'Scatter Plot: Nivel de Glucosa vs Presión Arterial Media en personas con la enfermedad');
        end

        % Button pushed function: UsuariosinEnfermedadButton_3
        function UsuariosinEnfermedadButton_3Pushed(app, event)
          
        heart_data = readtable('heart_data.csv');

        % Calcular el IMC para cada individuo
        IMC = heart_data.weight ./ (heart_data.height/100).^2; % Convertir altura a metros

        % Filtrar los datos para incluir solo a las personas que padecen la enfermedad
        indices_enfermos = (heart_data.cardio == 0);
        IMC_enfermos = IMC(indices_enfermos);
        colesterol_enfermos = heart_data.cholesterol(indices_enfermos);

        % Graficar scatter plot para personas con enfermedad en app.UIAxes
        scatter(app.UIAxes, IMC_enfermos, colesterol_enfermos);
        xlabel(app.UIAxes, 'Índice de Masa Corporal (IMC)');
        ylabel(app.UIAxes, 'Colesterol');
        title(app.UIAxes, 'Scatter Plot: IMC vs Colesterol en personas con la enfermedad');
        end

        % Button pushed function: UsuariosinEnfermedadButton_4
        function UsuariosinEnfermedadButton_4Pushed(app, event)
  
    heart_data = readtable('heart_data.csv');

    % Calcular la presión arterial media
    PresionAM = (heart_data.ap_lo * 2) + heart_data.ap_hi / 3;

    % Filtrar los datos para incluir solo a las personas que no padecen la enfermedad
    indices_sanos = (heart_data.cardio == 0);
    glucosa_sanos = heart_data.gluc(indices_sanos);
    PresionAM_sanos = PresionAM(indices_sanos);

    % Graficar scatter plot para personas sin enfermedad en app.UIAxes_2
    scatter(app.UIAxes_2, glucosa_sanos, PresionAM_sanos);
    xlabel(app.UIAxes_2, 'Nivel de Glucosa');
    ylabel(app.UIAxes_2, 'Presión Arterial Media');
    title(app.UIAxes_2, 'Scatter Plot: Nivel de Glucosa vs Presión Arterial Media en personas sin la enfermedad');
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Masa corporal vs Colesterol')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Glucosa vs Presión arterial media')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.AnlisisdeVariablesLabel = uilabel(app.UIFigure);
            app.AnlisisdeVariablesLabel.Text = 'Análisis de Variables';
            app.EligeunaopcinLabel = uilabel(app.UIFigure);
            app.EligeunaopcinLabel.Text = 'Elige una opción:';
            app.ndicedemasacorporalvsColesterolLabel = uilabel(app.UIFigure);
            app.ndicedemasacorporalvsColesterolLabel.Text = 'Índice de masa corporal vs Colesterol';
            app.UsuarioconEnfermedadButton_3 = uibutton(app.UIFigure, 'push');
            app.UsuarioconEnfermedadButton_3.ButtonPushedFcn = createCallbackFcn(app, @UsuarioconEnfermedadButton_3Pushed, true);
            app.UsuarioconEnfermedadButton_3.Text = 'Usuario con Enfermedad';
            app.UsuariosinEnfermedadButton_3 = uibutton(app.UIFigure, 'push');
            app.UsuariosinEnfermedadButton_3.ButtonPushedFcn = createCallbackFcn(app, @UsuariosinEnfermedadButton_3Pushed, true);
            app.UsuariosinEnfermedadButton_3.Text = 'Usuario sin Enfermedad';
            app.EligeunaopcinLabel_2 = uilabel(app.UIFigure);
            app.EligeunaopcinLabel_2.Text = 'Elige una opción:';
            app.NiveldeglucosavsPresionarterialmediaLabel = uilabel(app.UIFigure);
            app.NiveldeglucosavsPresionarterialmediaLabel.Text = 'Nivel de glucosa vs Presion arterial media';
            app.UsuarioconEnfermedadButton_4 = uibutton(app.UIFigure, 'push');
            app.UsuarioconEnfermedadButton_4.ButtonPushedFcn = createCallbackFcn(app, @UsuarioconEnfermedadButton_4Pushed, true);
            app.UsuarioconEnfermedadButton_4.Text = 'Usuario con Enfermedad';
            app.UsuariosinEnfermedadButton_4 = uibutton(app.UIFigure, 'push');
            app.UsuariosinEnfermedadButton_4.ButtonPushedFcn = createCallbackFcn(app, @UsuariosinEnfermedadButton_4Pushed, true);
            app.UsuariosinEnfermedadButton_4.Text = 'Usuario sin Enfermedad';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heart_Dispersion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end