classdef Heart_Estandar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        UsuariosconEnfermedadTab     matlab.ui.container.Tab
        ndicedemasacorporalButton    matlab.ui.control.Button
        ColesterolButton             matlab.ui.control.Button
        PresinarterialmediaButton    matlab.ui.control.Button
        DequvariabledeseasconocerlamediayladesviacinestndarLabel  matlab.ui.control.Label
        DESVIACIONESTANDARLabel      matlab.ui.control.Label
        MEDIALabel                   matlab.ui.control.Label
        UsuariossinEnfermedadTab     matlab.ui.container.Tab
        ndicedemasacorporalButton_2  matlab.ui.control.Button
        ColesterolButton_2           matlab.ui.control.Button
        PresinarterialmediaButton_2  matlab.ui.control.Button
        DequvariabledeseasconocerlamediayladesviacinestndarLabel_2  matlab.ui.control.Label
        DESVIACIONESTANDARLabel_2    matlab.ui.control.Label
        MEDIALabel_2                 matlab.ui.control.Label
        AnlisisdeVariablesLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PresinarterialmediaButton
        function PresinarterialmediaButtonPushed(app, event)
         % Cargar los datos desde el archivo CSV
        heart_data = readtable('heart_data.csv');
        % Calcular la presión arterial media utilizando la fórmula dada
        PresionAM = (heart_data.ap_lo * 2) + heart_data.ap_hi / 3;
        % Filtrar los datos para pacientes con enfermedad (suponiendo que la columna 'cardio' indica la presencia de la enfermedad)
        pacientes_con_enfermedad = PresionAM(heart_data.cardio == 1);
        % Calcular la media y desviación estándar
        media = mean(pacientes_con_enfermedad);
        desviacion_estandar = std(pacientes_con_enfermedad);
        % Mostrar la media y desviación estándar en las etiquetas correspondientes
        app.MEDIALabel.Text = sprintf('MEDIA: %.2f', media);
        app.DESVIACIONESTANDARLabel.Text = sprintf('DESVIACION ESTANDAR: %.2f', desviacion_estandar);
       
        end

        % Button pushed function: PresinarterialmediaButton_2
        function PresinarterialmediaButton_2Pushed(app, event)
             % Cargar los datos desde el archivo CSV
        heart_data = readtable('heart_data.csv');
        % Calcular la presión arterial media utilizando la fórmula dada
        PresionAM = (heart_data.ap_lo * 2) + heart_data.ap_hi / 3;
       
        % Filtrar los datos para pacientes sin enfermedad
        pacientes_sin_enfermedad = PresionAM(heart_data.cardio == 0);
        % Calcular la media y desviación estándar para pacientes sin enfermedad
        media_sanos = mean(pacientes_sin_enfermedad);
        desviacion_estandar_sanos = std(pacientes_sin_enfermedad);

        % Mostrar la media y desviación estándar para pacientes sin enfermedad en las etiquetas correspondientes
        app.MEDIALabel_2.Text = sprintf('MEDIA: %.2f', media_sanos);
        app.DESVIACIONESTANDARLabel_2.Text = sprintf('DESVIACION ESTANDAR: %.2f', desviacion_estandar_sanos);
        end

        % Button pushed function: ColesterolButton
        function ColesterolButtonPushed(app, event)
            % Cargar los datos desde el archivo CSV
        heart_data = readtable('heart_data.csv');
        
        % Filtrar los datos para pacientes con enfermedad
        pacientes_con_enfermedad = heart_data(heart_data.cardio == 1, :);
        
        % Calcular la media y desviación estándar del colesterol en pacientes con enfermedad
        media_colesterol_enfermos = mean(pacientes_con_enfermedad.cholesterol);
        desviacion_estandar_colesterol_enfermos = std(pacientes_con_enfermedad.cholesterol);
        
        % Mostrar la media y desviación estándar del colesterol en pacientes con enfermedad en las etiquetas correspondientes
        app.MEDIALabel.Text = sprintf('MEDIA: %.2f', media_colesterol_enfermos);
        app.DESVIACIONESTANDARLabel.Text = sprintf('DESVIACION ESTANDAR: %.2f', desviacion_estandar_colesterol_enfermos);
        end

        % Button pushed function: ColesterolButton_2
        function ColesterolButton_2Pushed(app, event)
            % Cargar los datos desde el archivo CSV
        heart_data = readtable('heart_data.csv');
        
        % Filtrar los datos para pacientes sin enfermedad
        pacientes_sin_enfermedad = heart_data(heart_data.cardio == 0, :);
        
        % Calcular la media y desviación estándar del colesterol en pacientes sin enfermedad
        media_colesterol_sanos = mean(pacientes_sin_enfermedad.cholesterol);
        desviacion_estandar_colesterol_sanos = std(pacientes_sin_enfermedad.cholesterol);
        
        % Mostrar la media y desviación estándar del colesterol en pacientes sin enfermedad en las etiquetas correspondientes
        app.MEDIALabel_2.Text = sprintf('MEDIA: %.2f', media_colesterol_sanos);
        app.DESVIACIONESTANDARLabel_2.Text = sprintf('DESVIACION ESTANDAR: %.2f', desviacion_estandar_colesterol_sanos);
        end

        % Button pushed function: ndicedemasacorporalButton
        function ndicedemasacorporalButtonPushed(app, event)
            % Cargar los datos desde el archivo CSV
        heart_data = readtable('heart_data.csv');
        
        % Calcular el índice de masa corporal (IMC) utilizando la fórmula IMC = peso (kg) / altura^2 (m^2)
        altura_metros = heart_data.height / 100; % Convertir altura a metros
        peso_kg = heart_data.weight; % Peso en kilogramos
        imc = peso_kg ./ (altura_metros.^2);
        
        % Filtrar los datos para pacientes con enfermedad
        pacientes_con_enfermedad = imc(heart_data.cardio == 1);
        
        % Calcular la media y desviación estándar del IMC en pacientes con enfermedad
        media_imc_enfermos = mean(pacientes_con_enfermedad);
        desviacion_estandar_imc_enfermos = std(pacientes_con_enfermedad);
        
        % Mostrar la media y desviación estándar del IMC en pacientes con enfermedad en las etiquetas correspondientes
        app.MEDIALabel.Text = sprintf('MEDIA IMC: %.2f', media_imc_enfermos);
        app.DESVIACIONESTANDARLabel.Text = sprintf('DESVIACION ESTANDAR IMC: %.2f', desviacion_estandar_imc_enfermos);
        end

        % Button pushed function: ndicedemasacorporalButton_2
        function ndicedemasacorporalButton_2Pushed(app, event)
             % Cargar los datos desde el archivo CSV
        heart_data = readtable('heart_data.csv');
        
        % Calcular el índice de masa corporal (IMC) utilizando la fórmula IMC = peso (kg) / altura^2 (m^2)
        altura_metros = heart_data.height / 100; % Convertir altura a metros
        peso_kg = heart_data.weight; % Peso en kilogramos
        imc = peso_kg ./ (altura_metros.^2);
        
        % Filtrar los datos para pacientes sin enfermedad
        pacientes_sin_enfermedad = imc(heart_data.cardio == 0);
        
        % Calcular la media y desviación estándar del IMC en pacientes sin enfermedad
        media_imc_sanos = mean(pacientes_sin_enfermedad);
        desviacion_estandar_imc_sanos = std(pacientes_sin_enfermedad);
        
        % Mostrar la media y desviación estándar del IMC en pacientes sin enfermedad en las etiquetas correspondientes
        app.MEDIALabel_2.Text = sprintf('MEDIA IMC: %.2f', media_imc_sanos);
        app.DESVIACIONESTANDARLabel_2.Text = sprintf('DESVIACION ESTANDAR IMC: %.2f', desviacion_estandar_imc_sanos);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnlisisdeVariablesLabel = uilabel(app.UIFigure);
            app.AnlisisdeVariablesLabel.Text = 'Desviación Estándar: Enfermedades Cardiovasculares vs. Sin Enfermedades';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UsuariosconEnfermedadTab = uitab(app.TabGroup);
            app.UsuariosconEnfermedadTab.Title = 'Usuarios con Enfermedad';
            app.MEDIALabel = uilabel(app.UsuariosconEnfermedadTab);
            app.MEDIALabel.Text = 'MEDIA: ';
            app.DESVIACIONESTANDARLabel = uilabel(app.UsuariosconEnfermedadTab);
            app.DESVIACIONESTANDARLabel.Text = 'DESVIACION ESTANDAR: ';
            app.DequvariabledeseasconocerlamediayladesviacinestndarLabel = uilabel(app.UsuariosconEnfermedadTab);
            app.DequvariabledeseasconocerlamediayladesviacinestndarLabel.Text = {'¿De qué variable deseas'; ' conocer la media y la desviación estándar? '};
            app.PresinarterialmediaButton = uibutton(app.UsuariosconEnfermedadTab, 'push');
            app.PresinarterialmediaButton.ButtonPushedFcn = createCallbackFcn(app, @PresinarterialmediaButtonPushed, true);
            app.PresinarterialmediaButton.Text = 'Presión arterial media';
            app.ColesterolButton = uibutton(app.UsuariosconEnfermedadTab, 'push');
            app.ColesterolButton.ButtonPushedFcn = createCallbackFcn(app, @ColesterolButtonPushed, true);
            app.ColesterolButton.Text = 'Colesterol';
            app.ndicedemasacorporalButton = uibutton(app.UsuariosconEnfermedadTab, 'push');
            app.ndicedemasacorporalButton.ButtonPushedFcn = createCallbackFcn(app, @ndicedemasacorporalButtonPushed, true);
            app.ndicedemasacorporalButton.Text = ' Índice de masa corporal';
            app.UsuariossinEnfermedadTab = uitab(app.TabGroup);
            app.UsuariossinEnfermedadTab.Title = 'Usuarios sin Enfermedad';
            app.MEDIALabel_2 = uilabel(app.UsuariossinEnfermedadTab);
            app.MEDIALabel_2.Text = 'MEDIA: ';
            app.DESVIACIONESTANDARLabel_2 = uilabel(app.UsuariossinEnfermedadTab);
            app.DESVIACIONESTANDARLabel_2.Text = 'DESVIACION ESTANDAR: ';
            app.DequvariabledeseasconocerlamediayladesviacinestndarLabel_2 = uilabel(app.UsuariossinEnfermedadTab);
            app.DequvariabledeseasconocerlamediayladesviacinestndarLabel_2.Text = {'¿De qué variable deseas'; ' conocer la media y la desviación estándar? '};
            app.PresinarterialmediaButton_2 = uibutton(app.UsuariossinEnfermedadTab, 'push');
            app.PresinarterialmediaButton_2.ButtonPushedFcn = createCallbackFcn(app, @PresinarterialmediaButton_2Pushed, true);
            app.PresinarterialmediaButton_2.Text = 'Presión arterial media';
            app.ColesterolButton_2 = uibutton(app.UsuariossinEnfermedadTab, 'push');
            app.ColesterolButton_2.ButtonPushedFcn = createCallbackFcn(app, @ColesterolButton_2Pushed, true);
            app.ColesterolButton_2.Text = 'Colesterol';
            app.ndicedemasacorporalButton_2 = uibutton(app.UsuariossinEnfermedadTab, 'push');
            app.ndicedemasacorporalButton_2.ButtonPushedFcn = createCallbackFcn(app, @ndicedemasacorporalButton_2Pushed, true);
            app.ndicedemasacorporalButton_2.Text = ' Índice de masa corporal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heart_Estandar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end