classdef Heart_Interfaz_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        QueDeseasObservarButtonGroup  matlab.ui.container.ButtonGroup
        BsquedadeParticipantesporIDyCaractersticasAsociadasButton  matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        RelacinentreVariablesBiomtricasButton  matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        RiesgodeEnfermedadesCardiovascularesLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Heart_Interfaz_OpeningFcn(app, varargin)
            % --- Executes just before Heart_Interfaz is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Heart_Interfaz (see VARARGIN)
            
            % Choose default command line output for Heart_Interfaz
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           Heart_Barras (); 
        end

        % Button pushed function: 
        % BsquedadeParticipantesporIDyCaractersticasAsociadasButton
        function BsquedadeParticipantesporIDyCaractersticasAsociadasButtonPushed(app, event)
            Heart_Usuario ();
        end

        % Button pushed function: RelacinentreVariablesBiomtricasButton
        function RelacinentreVariablesBiomtricasButtonPushed(app, event)
            Heart_Dispersion ();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Heart_Estandar (); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Heart_Interfaz';
            app.figure1.Tag = 'figure1';
            app.RiesgodeEnfermedadesCardiovascularesLabel = uilabel(app.figure1);
            app.RiesgodeEnfermedadesCardiovascularesLabel.Text = 'Riesgo de Enfermedades Cardiovasculares ';
            app.QueDeseasObservarButtonGroup = uibuttongroup(app.figure1);
            app.QueDeseasObservarButtonGroup.Title = '¿Que Deseas Observar?';
            app.Button = uibutton(app.QueDeseasObservarButtonGroup, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Prevalencia de Enfermedades Cardiovasculares por Género y Grupos de Edad';
            app.RelacinentreVariablesBiomtricasButton = uibutton(app.QueDeseasObservarButtonGroup, 'push');
            app.RelacinentreVariablesBiomtricasButton.ButtonPushedFcn = createCallbackFcn(app, @RelacinentreVariablesBiomtricasButtonPushed, true);
            app.RelacinentreVariablesBiomtricasButton.Text = 'Relación entre Variables Biométricas';
            app.Button_2 = uibutton(app.QueDeseasObservarButtonGroup, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Comparación de Variables Biométricas según la Presencia de Enfermedades Cardiovasculares';
            app.BsquedadeParticipantesporIDyCaractersticasAsociadasButton = uibutton(app.QueDeseasObservarButtonGroup, 'push');
            app.BsquedadeParticipantesporIDyCaractersticasAsociadasButton.ButtonPushedFcn = createCallbackFcn(app, @BsquedadeParticipantesporIDyCaractersticasAsociadasButtonPushed, true);
            app.BsquedadeParticipantesporIDyCaractersticasAsociadasButton.Text = 'Búsqueda de Participantes por ID y Características Asociadas';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heart_Interfaz_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Heart_Interfaz_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end