classdef Heart_Usuario < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NiveldeColesterolLabel3       matlab.ui.control.Label
        NiveldeGlucosaLabel           matlab.ui.control.Label
        CategoriaIMCLabel2            matlab.ui.control.Label
        AlturaLabel                   matlab.ui.control.Label
        PesoLabel                     matlab.ui.control.Label
        EdadLabel                     matlab.ui.control.Label
        GeneroLabel                   matlab.ui.control.Label
        PracticaActividadFisicaLabel  matlab.ui.control.Label
        ConsumealcoholLabel           matlab.ui.control.Label
        FumadorLabel                  matlab.ui.control.Label
        ElparticipantepadecelaenfermedadLabel  matlab.ui.control.Label
        ConsultatusDatosLabel         matlab.ui.control.Label
        ConsultarButton               matlab.ui.control.Button
        IDEditField                   matlab.ui.control.NumericEditField
        IDEditFieldLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConsultarButton
        function ConsultarButtonPushed(app, event)
           heart_data = readtable('heart_data.csv');

           % Mostrar el mensaje descriptivo inicial en los labels
app.ElparticipantepadecelaenfermedadLabel.Text = 'El participante padece la enfermedad: ';
app.FumadorLabel.Text = 'El participante es fumador: ';
app.ConsumealcoholLabel.Text = 'El participante consume alcohol: ';
app.PracticaActividadFisicaLabel.Text = 'El participante hace actividad física: ';
app.GeneroLabel.Text = 'El género del participante es: ';
app.EdadLabel.Text = 'Edad del participante: ';
app.PesoLabel.Text = 'Peso del participante: ';
app.AlturaLabel.Text = 'Altura del participante: ';
app.CategoriaIMCLabel2.Text = 'Categoría de índice de masa corporal del participante: ';
app.NiveldeGlucosaLabel.Text = 'Nivel de glucosa del participante: ';
app.NiveldeColesterolLabel3.Text = 'Nivel de colesterol del participante: ';

% Obtener el ID ingresado por el usuario
participantID = app.IDEditField.Value;

% Buscar al participante en la base de datos
participantIndex = find(heart_data.id == participantID);
if ~isempty(participantIndex)
    participant = heart_data(participantIndex, :);

    % Mostrar la información del participante en la interfaz gráfica
    
    % Mostrar si el participante padece la enfermedad cardiovascular
    if participant.cardio == 1
        app.ElparticipantepadecelaenfermedadLabel.Text = ['El participante padece la enfermedad: ', 'Sí'];
    else
        app.ElparticipantepadecelaenfermedadLabel.Text = ['El participante padece la enfermedad: ', 'No'];
    end

    % Mostrar si el participante es fumador
    if participant.smoke == 1
        app.FumadorLabel.Text = ['El participante es fumador: ', 'Sí'];
    else
        app.FumadorLabel.Text = ['El participante es fumador: ', 'No'];
    end

    % Mostrar si el participante consume alcohol
    if participant.alco == 1
        app.ConsumealcoholLabel.Text = ['El participante consume alcohol: ', 'Sí'];
    else
        app.ConsumealcoholLabel.Text = ['El participante consume alcohol: ', 'No'];
    end

    % Mostrar si el participante hace actividad física
    if participant.active == 1
        app.PracticaActividadFisicaLabel.Text = ['El participante hace actividad física: ', 'Sí'];
    else
        app.PracticaActividadFisicaLabel.Text = ['El participante hace actividad física: ', 'No'];
    end

    % Mostrar si el participante es hombre o mujer
    if participant.gender == 1
        app.GeneroLabel.Text = ['El género del participante es: ', 'Hombre'];
    elseif participant.gender == 2
        app.GeneroLabel.Text = ['El género del participante es: ', 'Mujer'];
    else
        app.GeneroLabel.Text = 'El género del participante es desconocido';
    end
    
  % Convertir la edad de días a años y redondear
participant.age = round(participant.age / 365.25);

% Mostrar la edad del participante
app.EdadLabel.Text = ['Edad del participante: ', num2str(participant.age), ' años'];

    
    % Mostrar el peso del participante
    app.PesoLabel.Text = ['Peso del participante: ', num2str(participant.weight), ' kg'];
    
    % Mostrar la altura del participante
    app.AlturaLabel.Text = ['Altura del participante: ', num2str(participant.height), ' cm'];

    % Calcular el IMC del participante
    IMC_participante = participant.weight / (participant.height/100)^2;

    % Clasificar el IMC del participante
    if IMC_participante < 18.5
        clasificacion_IMC = 'Peso insuficiente';
    elseif IMC_participante < 25
        clasificacion_IMC = 'Peso normal o saludable';
    elseif IMC_participante < 30
        clasificacion_IMC = 'Sobrepeso';
    else
        clasificacion_IMC = 'Obesidad';
    end

    % Mostrar la categoría de índice de masa corporal
    app.CategoriaIMCLabel2.Text = ['Categoría de índice de masa corporal del participante: ', clasificacion_IMC];
    
    % Mostrar el nivel de glucosa del participante
    if participant.gluc == 1
        app.NiveldeGlucosaLabel.Text = ['Nivel de glucosa del participante: ', 'Bajo'];
    elseif participant.gluc == 2
        app.NiveldeGlucosaLabel.Text = ['Nivel de glucosa del participante: ', 'Normal'];
    elseif participant.gluc == 3
        app.NiveldeGlucosaLabel.Text = ['Nivel de glucosa del participante: ', 'Alto'];
    else
        app.NiveldeGlucosaLabel.Text = 'Nivel de glucosa del participante desconocido';
    end
    
    % Mostrar el nivel de colesterol del participante
    if participant.cholesterol == 1
        app.NiveldeColesterolLabel3.Text = ['Nivel de colesterol del participante: ', 'Bajo'];
    elseif participant.cholesterol == 2
        app.NiveldeColesterolLabel3.Text = ['Nivel de colesterol del participante: ', 'Normal'];
    elseif participant.cholesterol == 3
        app.NiveldeColesterolLabel3.Text = ['Nivel de colesterol del participante: ', 'Alto'];
    else
        app.NiveldeColesterolLabel3.Text = 'Nivel de colesterol del participante desconocido';
    end
else
    % Mostrar un mensaje de error si no se encuentra el participante
    uialert(app.UIFigure, 'No se encontró ningún participante con ese ID', 'Error de Búsqueda');
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IDEditFieldLabel = uilabel(app.UIFigure);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConsultarButton = uibutton(app.UIFigure, 'push');
            app.ConsultarButton.ButtonPushedFcn = createCallbackFcn(app, @ConsultarButtonPushed, true);
            app.ConsultarButton.Text = 'Consultar';
            app.ConsultatusDatosLabel = uilabel(app.UIFigure);
            app.ConsultatusDatosLabel.Text = 'Consulta tus Datos ';
            app.ElparticipantepadecelaenfermedadLabel = uilabel(app.UIFigure);
            app.ElparticipantepadecelaenfermedadLabel.Text = 'El participante padece la enfermedad:   ';
            app.FumadorLabel = uilabel(app.UIFigure);
            app.FumadorLabel.Text = 'Fumador: ';
            app.ConsumealcoholLabel = uilabel(app.UIFigure);
            app.ConsumealcoholLabel.Text = 'Consume alcohol: ';
            app.PracticaActividadFisicaLabel = uilabel(app.UIFigure);
            app.PracticaActividadFisicaLabel.Text = 'Practica Actividad Fisica: ';
            app.GeneroLabel = uilabel(app.UIFigure);
            app.GeneroLabel.Text = 'El género del participante es: ';
            app.EdadLabel = uilabel(app.UIFigure);
            app.EdadLabel.Text = 'Edad del participante: ';
            app.PesoLabel = uilabel(app.UIFigure);
            app.PesoLabel.Text = 'Peso del participante: ';
            app.AlturaLabel = uilabel(app.UIFigure);
            app.AlturaLabel.Text = 'Altura del participante: ';
            app.CategoriaIMCLabel2 = uilabel(app.UIFigure);
            app.CategoriaIMCLabel2.Text = 'Categoría de índice de masa corporal del participante: ';
            app.NiveldeGlucosaLabel = uilabel(app.UIFigure);
            app.NiveldeGlucosaLabel.Text = 'Nivel de glucosa del participante: ';
            app.NiveldeColesterolLabel3 = uilabel(app.UIFigure);
            app.NiveldeColesterolLabel3.Text = 'Nivel de colesterol del participante:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heart_Usuario

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end